classdef BMI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        yourname                      matlab.ui.control.EditField
        massinput                     matlab.ui.control.EditField
        heightinput                   matlab.ui.control.EditField
        CalculateButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        header                        matlab.ui.control.EditField
        showBMI                       matlab.ui.control.EditField
        merrorEditField               matlab.ui.control.EditField
        herrorEditField               matlab.ui.control.EditField
        yourmassinkgEditFieldLabel_2  matlab.ui.control.Label
        yourheightincmLabel           matlab.ui.control.Label
        yournameEditFieldLabel        matlab.ui.control.Label
        nerror                        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        row_number=1; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                  
            h = str2double(app.heightinput.Value); % Get
            m = str2double(app.massinput.Value); % Get
 
   % set error for name
   name = app.yourname.Value;
   if isempty(name)
      app.nerror.Visible= 'on';
      app.nerror.Value = 'please enter your name';
   end            
    %set errors for the mass
if  m<=0 
    app.merrorEditField.Visible= 'on';
    app.merrorEditField.Value = 'mass cannot be zero or a negative number';
    elseif  m>500
    app.merrorEditField.Visible= 'on';
    app.merrorEditField.Value = 'check the mass !'; 
    elseif isnan(m)
    app.merrorEditField.Visible= 'on';    
    app.merrorEditField.Value = 'mass cannot be a word or left blank'; 
end
   
    %set errors for the height
if  h<=0 
    app.herrorEditField.Visible= 'on';
    app.herrorEditField.Value = 'height cannot be zero or a negative number';
    elseif  h>300 
    app.herrorEditField.Visible= 'on';
    app.herrorEditField.Value = 'check the height !';
    elseif isnan(h)
    app.herrorEditField.Visible= 'on';
    app.herrorEditField.Value = 'height cannot be a word or left blank';
end
   
    %calculate BMI
if  m>500 || h>300
    return
    elseif isempty(name)
        return 
    elseif m>0 && h>0
        BMI= BMI_calc(m,h);
         app.SaveButton.Enable='on';
         %set BMI message
          app.showBMI.Visible= 'on';
         if BMI<18
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are underweight'];
            elseif BMI>=18 && BMI<=18.5
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are thin for your height'];
            elseif BMI>18.5 && BMI<25
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are at healthy weight :)'];
            elseif BMI>=25 && BMI<30
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are overweight'];
            elseif BMI>=30 && BMI<35
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are obese!'];
            elseif BMI>=35 && BMI<40
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are severely obese!'];
            elseif BMI>=40 && BMI<50
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are morbidly obese!'];
            else
            app.showBMI.Value = ['Your BMI is ' num2str(BMI) ', you are super obese!'];
        end
      else
        return
end
         
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.herrorEditField.Visible= 'off';
            app.merrorEditField.Visible= 'off';
            app.showBMI.Visible= 'off';
            app.nerror.Visible= 'off';
            app.heightinput.Value  = '';
            app.massinput.Value  = '';
            app.yourname.Value  = '';
            app.herrorEditField.Value  ='';
            app.merrorEditField.Value  ='';
            app.showBMI.Value ='';
            app.CalculateButton.Enable = 'on';
            app.nerror.Value= '';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            BMI= BMI_calc(str2double(app.massinput.Value),...
                str2double(app.heightinput.Value));
             
            tbl= table(string(app.yourname.Value),string(app.massinput.Value),...
                string(app.heightinput.Value), BMI,...
                'Variablenames',["name","body mass(kg)","height(cm)","BMI"]);
            app.row_number =app. row_number+1;
            writetable(tbl,'C:\BMIdata.xlsx','Range', ['A', num2str(app.row_number)], 'WriteVariableNames', false);
                    
        end

        % Value changed function: yourname
        function yournameValueChanged(app, event)
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.yourname = uieditfield(app.UIFigure, 'text');
            app.yourname.ValueChangedFcn = createCallbackFcn(app, @yournameValueChanged, true);
            app.massinput = uieditfield(app.UIFigure, 'text');
            app.heightinput = uieditfield(app.UIFigure, 'text');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset!';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.header = uieditfield(app.UIFigure, 'text');
            app.header.Value = 'Body-Mass Index Calculator';
            app.showBMI = uieditfield(app.UIFigure, 'text');
            app.showBMI.Visible = 'off';
            app.merrorEditField = uieditfield(app.UIFigure, 'text');
            app.merrorEditField.Visible = 'off';
            app.herrorEditField = uieditfield(app.UIFigure, 'text');
            app.herrorEditField.Visible = 'off';
            app.yourmassinkgEditFieldLabel_2 = uilabel(app.UIFigure);
            app.yourmassinkgEditFieldLabel_2.Text = 'your mass in kg';
            app.yourheightincmLabel = uilabel(app.UIFigure);
            app.yourheightincmLabel.Text = 'your height in cm';
            app.yournameEditFieldLabel = uilabel(app.UIFigure);
            app.yournameEditFieldLabel.Text = 'your name';
            app.nerror = uieditfield(app.UIFigure, 'text');
            app.nerror.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end